classdef PID_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PIDPanel                  matlab.ui.container.Panel
        Spinner_10                matlab.ui.control.Spinner
        Spinner_9                 matlab.ui.control.Spinner
        Spinner_8                 matlab.ui.control.Spinner
        KPSlider                  matlab.ui.control.Slider
        KPSliderLabel             matlab.ui.control.Label
        KDSlider                  matlab.ui.control.Slider
        KDSliderLabel             matlab.ui.control.Label
        KISlider                  matlab.ui.control.Slider
        KISliderLabel             matlab.ui.control.Label
        Spinner_6                 matlab.ui.control.Spinner
        Spinner_5                 matlab.ui.control.Spinner
        Spinner_4                 matlab.ui.control.Spinner
        InputSignalButtonGroup    matlab.ui.container.ButtonGroup
        SinButton                 matlab.ui.control.RadioButton
        ImpulseButton             matlab.ui.control.RadioButton
        StepButton                matlab.ui.control.RadioButton
        TabGroup2                 matlab.ui.container.TabGroup
        ImpulseTab                matlab.ui.container.Tab
        MagnitudeSpinner          matlab.ui.control.Spinner
        MagnitudeSpinnerLabel     matlab.ui.control.Label
        StepTab                   matlab.ui.container.Tab
        DelaySpinner              matlab.ui.control.Spinner
        DelaySpinnerLabel         matlab.ui.control.Label
        MagnitudeSpinner_2        matlab.ui.control.Spinner
        MagnitudeSpinner_2Label   matlab.ui.control.Label
        SinTab                    matlab.ui.container.Tab
        Spinner_7                 matlab.ui.control.Spinner
        Label                     matlab.ui.control.Label
        MagnitudeSpinner_3        matlab.ui.control.Spinner
        MagnitudeSpinner_3Label   matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        OTFTab                    matlab.ui.container.Tab
        Lamp                      matlab.ui.control.Lamp
        s2Label                   matlab.ui.control.Label
        s1Label                   matlab.ui.control.Label
        s0Label                   matlab.ui.control.Label
        S2Spinner                 matlab.ui.control.Spinner
        S1Spinner                 matlab.ui.control.Spinner
        S0Spinner                 matlab.ui.control.Spinner
        PZTab                     matlab.ui.container.Tab
        EditField_6               matlab.ui.control.NumericEditField
        EditField_5               matlab.ui.control.NumericEditField
        EditField_4               matlab.ui.control.NumericEditField
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        ZerosLabel                matlab.ui.control.Label
        PolesLabel                matlab.ui.control.Label
        Lamp_2                    matlab.ui.control.Lamp
        WorkspaceTab              matlab.ui.container.Tab
        Lamp_3                    matlab.ui.control.Lamp
        ListBox                   matlab.ui.control.ListBox
        ListBoxLabel              matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        TabGroup3                 matlab.ui.container.TabGroup
        ResponseTab               matlab.ui.container.Tab
        PerformanceIndexTextArea  matlab.ui.control.TextArea
        ResetButton               matlab.ui.control.Button
        GenerateButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        SimulationTab             matlab.ui.container.Tab
        Lamp_4                    matlab.ui.control.Lamp
        AngleEditField            matlab.ui.control.NumericEditField
        AngleEditFieldLabel       matlab.ui.control.Label
        PositionEditField         matlab.ui.control.NumericEditField
        PositionEditFieldLabel    matlab.ui.control.Label
        TimeEditField             matlab.ui.control.NumericEditField
        TimeEditFieldLabel        matlab.ui.control.Label
        SimulateButton            matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: KPSlider
        function KPSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_8.Value = changingValue;
            KP = changingValue;
            KI = app.KISlider.Value;
            KD = app.KDSlider.Value;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            
            %判断输入信号
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;
            end
            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end
            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end    
            %判断输入传函
            if app.Lamp.Color == [0 1 0]
                A = app.S0Spinner.Value;
                B = app.S1Spinner.Value;
                C = app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end
           
            
            if app.Lamp_2.Color == [0 1 0]
                p = [];
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end

            if app.Lamp_3.Color == [0 1 0]
               A = evalin('base', app.ListBox.Value); 
               OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);            
            end
            
            fplot(app.UIAxes,OUTPUT,[0 100])
            grid(app.UIAxes,"on") 

            % %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end
        end

        % Value changed function: KISlider
        function KISliderValueChangging(app, event)
            changingvalue = event.Value;
            app.Spinner_9.Value = changingvalue;
            KP = app.KPSlider.Value;
            KI = changingvalue;
            KD = app.KDSlider.Value;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;
            end
            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end
            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end              
            
            %判断输入传函
           if app.Lamp.Color == [0 1 0]
                A = app.S0Spinner.Value;
                B = app.S1Spinner.Value;
                C = app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end
            
            if app.Lamp_2.Color == [0 1 0]
                p = [];
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end

            if app.Lamp_3.Color == [0 1 0]
                A = evalin('base', app.ListBox.Value);
                OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);
            end
            fplot(app.UIAxes,OUTPUT,[0 100])
            grid(app.UIAxes,"on")

            %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else 
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end
        end

        % Value changing function: KDSlider
        function KDSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_10.Value = changingValue;
            KP = app.KPSlider.Value;
            KI = app.KISlider.Value;
            KD = changingValue;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;
            end
            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end
            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end              
            
            %判断输入传函
           if app.Lamp.Color == [0 1 0]
                A = app.S0Spinner.Value;
                B = app.S1Spinner.Value;
                C = app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end
            
            if app.Lamp_2.Color == [0 1 0]
                p = [];
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end

            if app.Lamp_3.Color == [0 1 0]
               A = evalin('base', app.ListBox.Value); 
               OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);            
            end
            
            fplot(app.UIAxes,OUTPUT,[0 100])   
            grid(app.UIAxes,"on")

            %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else 
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            KP = app.KPSlider.Value;
            KI = app.KISlider.Value;
            KD = app.KDSlider.Value;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;                
            end

            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end

            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end
            
            %判断输入传函
           if app.Lamp.Color == [0 1 0]
                A = app.S0Spinner.Value;
                B = app.S1Spinner.Value;
                C = app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end
            
            if app.Lamp_2.Color == [0 1 0]
                p = [];
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end

            if app.Lamp_3.Color == [0 1 0]
               A = evalin('base', app.ListBox.Value); 
               OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);            
            end
            
            fplot(app.UIAxes,OUTPUT,[0 100])
            grid(app.UIAxes,"on")

            %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else 
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end
        end

        % Value changing function: Spinner_4
        function Spinner_4ValueChanging(app, event)
            changingValue = event.Value;
            app.KPSlider.Limits = [1,changingValue];
        end

        % Value changing function: Spinner_5
        function Spinner_5ValueChanging(app, event)
            changingValue = event.Value;
            app.KISlider.Limits = [0,changingValue];
        end

        % Value changing function: Spinner_6
        function Spinner_6ValueChanging(app, event)
            changingValue = event.Value;
            app.KDSlider.Limits = [0,changingValue];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
          app.KPSlider.Limits = [1,100];
          app.KISlider.Limits = [0,100];
          app.KDSlider.Limits = [0,100];
          app.KPSlider.Value = 1;
          app.KISlider.Value = 0;
          app.KDSlider.Value = 0;
          app.StepButton.Value = 1;
          app.ImpulseButton.Value = 0;
          app.SinButton.Value = 0;
          app.Spinner_4.Value = 100;
          app.Spinner_5.Value = 100;
          app.Spinner_6.Value = 100;
          app.UIAxes.XLim = [0 20];
          app.UIAxes.YLim = [-10 20];
          app.EditField.Value = 1;
          app.EditField_2.Value = 1;
          app.EditField_3.Value = 1;
          app.EditField_4.Value = 1;
          app.EditField_5.Value = 1;
          app.EditField_6.Value = 1;
          app.S0Spinner.Value = 1;
          app.S1Spinner.Value = 1;
          app.S2Spinner.Value = 1;
          GenerateButtonPushed(app, event)
        end

        % Value changing function: S0Spinner
        function S0SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [0 1 0];
            app.Lamp_2.Color = [0.65 0.65 0.65];
            app.Lamp_3.Color = [0.65 0.65 0.65];

        end

        % Value changing function: S1Spinner
        function S1SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [0 1 0];
            app.Lamp_2.Color = [0.65 0.65 0.65];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changing function: S2Spinner
        function S2SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [0 1 0];
            app.Lamp_2.Color = [0.65 0.65 0.65];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0 1 0];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0 1 0];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0 1 0];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0 1 0];            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
             value = app.EditField_5.Value;
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0 1 0];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
             value = app.EditField_6.Value;
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0 1 0];
            app.Lamp_3.Color = [0.65 0.65 0.65];
        end

        % Value changing function: MagnitudeSpinner
        function MagnitudeSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.ImpulseButton.Value = 1;
        end

        % Value changing function: MagnitudeSpinner_2
        function MagnitudeSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.StepButton.Value = 1;
        end

        % Value changing function: DelaySpinner
        function DelaySpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.StepButton.Value = 1;
        end

        % Value changing function: MagnitudeSpinner_3
        function MagnitudeSpinner_3ValueChanging(app, event)
            changingValue = event.Value;
            app.SinButton.Value = 1;
        end

        % Value changing function: Spinner_7
        function Spinner_7ValueChanging(app, event)
            changingValue = event.Value;
            app.SinButton.Value = 1;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % A = evalin('base' , value); 
            app.Lamp.Color = [0.65 0.65 0.65];
            app.Lamp_2.Color = [0.65 0.65 0.65];
            app.Lamp_3.Color = [0 1 0];
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
                        
            % PID 控制器参数
            KP = app.KPSlider.Value;
            KI = app.KISlider.Value;
            KD = app.KDSlider.Value; 
            % 定义仿真参数
            x0 = app.PositionEditField.Value;
            detla_pi = app.AngleEditField.Value;
            t_sim = app.TimeEditField.Value;
            
            app.Lamp_4.Color = [0 1 0];
            ax = app.UIAxes2;
            che(ax, KP, KI, KD, x0, detla_pi, t_sim);
            clear cartpend_pid
            rehash
            % che2(KP, KI, KD, x0, detla_pi, t_sim);
            app.Lamp_4.Color = [0.65 0.65 0.65];
            
            
        end

        % Value changed function: Spinner_8
        function Spinner_8ValueChanged(app, event)
            value = app.Spinner_8.Value;
            app.KPSlider.Value = value;            
            KP = app.KPSlider.Value;
            KI = app.KISlider.Value;
            KD = app.KDSlider.Value;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            %判断输入信号
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;
            end
            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end
            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end    

            %判断输入传函
           if app.Lamp.Color == [0 1 0]
                A = app.S0Spinner.Value;
                B = app.S1Spinner.Value;
                C = app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end
            
            if app.Lamp_2.Color == [0 1 0]
                p = [];
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end

            if app.Lamp_3.Color == [0 1 0]
               A = evalin('base', app.ListBox.Value); 
               OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);            
            end
            
            fplot(app.UIAxes,OUTPUT,[0 100])
            grid(app.UIAxes,"on")  

            %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else 
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end            
        end

        % Value changed function: Spinner_10
        function Spinner_10ValueChanged(app, event)
            value = app.Spinner_10.Value;
            app.KDSlider.Value = value;
            KP = app.KPSlider.Value;
            KI = app.KISlider.Value;
            KD = app.KDSlider.Value;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            %判断输入信号
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;
            end
            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end
            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end    
           
            %判断输入传函
            if app.Lamp.Color == [0 1 0]
                A = app.S0Spinner.Value;
                B = app.S1Spinner.Value;
                C = app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end

            if app.Lamp_2.Color == [0 1 0]
                p = [];
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end               
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end

            if app.Lamp_3.Color == [0 1 0]
               A = evalin('base', app.ListBox.Value); 
               OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);            
            end
            
            fplot(app.UIAxes, OUTPUT, [0 100])
            grid(app.UIAxes,"on")  

            %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else 
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end
        end

        % Value changed function: Spinner_9
        function Spinner_9ValueChanged(app, event)
            value = app.Spinner_9.Value;
            app.KISlider.Value = value;
            KP = app.KPSlider.Value;
            KI = app.KISlider.Value;
            KD = app.KDSlider.Value;
            in = app.StepButton.Value;
            ifsin = 0;
            delay = 0;
            freq = 0;
            
            %判断输入信号
            if app.ImpulseButton.Value == 1
                K = app.MagnitudeSpinner.Value;
            end
            if app.StepButton.Value == 1
                K = app.MagnitudeSpinner_2.Value;
                delay = app.DelaySpinner.Value;
            end
            if app.SinButton.Value == 1
                K = app.MagnitudeSpinner_3.Value;
                freq = app.Spinner_7.Value;
                ifsin = 1;
            end    
            %判断输入传函
            if app.Lamp.Color == [0 1 0]
                A=app.S0Spinner.Value;
                B=app.S1Spinner.Value;
                C=app.S2Spinner.Value;
                OUTPUT = PIDtest(K,KP,KI,KD,A,B,C,in,delay,freq,ifsin);
            end
            
            if app.Lamp_2.Color == [0 1 0]
                
                P1 = app.EditField.Value;
                P2 = app.EditField_2.Value;
                P3 = app.EditField_3.Value;
                P4 = app.EditField_4.Value;
                Z1 = app.EditField_5.Value;
                Z2 = app.EditField_6.Value;
                
                p = [];
                if P1<0||P1 == 0
                    p=[p P1];
                end
                if P2<0||P2 == 0
                    p=[p P2];
                end
                if P3<0||P3 == 0
                    p=[p P3];
                end
                if P4<0||P4 == 0
                    p=[p P4];
                end    

                
                z=[];
                if real(Z1)<0||real(Z1) == 0
                    z=[z Z1];
                end
                if real(Z2)<0||real(Z2) == 0
                    z=[z Z2];
                end

                
                OUTPUT = PIDtest2(K,KP,KI,KD,p,z,in,delay,freq,ifsin);
            end


            if app.Lamp_3.Color == [0 1 0]
               A = evalin('base', app.ListBox.Value); 
               OUTPUT = PIDtest3(K,KP,KI,KD,A,in,delay,freq,ifsin);            
            end
            
            fplot(app.UIAxes, OUTPUT, [0 100])
            grid(app.UIAxes,"on")  

            % %显示性能指标
            % if app.StepButton.Value == 1
            %     app.PerformanceIndexTextArea.Visible = "on";
            %     sys_tf = sym2tf(GTF);
            % 
            %     % 创建自定义阶跃输入
            %     t_start = app.DelaySpinner.Value;  % 阶跃发生时间
            %     step_amplitude = app.MagnitudeSpinner_2.Value;  % 阶跃的幅值
            %     t = 0:0.01:100;  % 时间向量
            %     u = step_amplitude * (t >= t_start);  % 阶跃输入
            % 
            %     % 模拟系统响应
            %     [y, t] = lsim(sys_tf, u, t);
            % 
            %     % 计算性能指标
            %     dt = t(2) - t(1);  % 时间间隔
            %     text= dynamicproperties(y, dt);
            %     app.PerformanceIndexTextArea.Value = text;
            % else 
            %     app.PerformanceIndexTextArea.Visible = "off";
            % end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {701, 701};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {340, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.OTFTab = uitab(app.TabGroup);
            app.OTFTab.Title = 'OTF';
            app.S0Spinner = uispinner(app.OTFTab);
            app.S0Spinner.Step = 0.1;
            app.S0Spinner.ValueChangingFcn = createCallbackFcn(app, @S0SpinnerValueChanging, true);
            app.S0Spinner.Limits = [-10 10];
            app.S0Spinner.Value = 1;
            app.S1Spinner = uispinner(app.OTFTab);
            app.S1Spinner.Step = 0.1;
            app.S1Spinner.ValueChangingFcn = createCallbackFcn(app, @S1SpinnerValueChanging, true);
            app.S1Spinner.Limits = [-10 10];
            app.S1Spinner.Value = 1;
            app.S2Spinner = uispinner(app.OTFTab);
            app.S2Spinner.Step = 0.1;
            app.S2Spinner.ValueChangingFcn = createCallbackFcn(app, @S2SpinnerValueChanging, true);
            app.S2Spinner.Limits = [-10 10];
            app.S2Spinner.Value = 1;
            app.s0Label = uilabel(app.OTFTab);
            app.s0Label.Text = 's^0';
            app.s1Label = uilabel(app.OTFTab);
            app.s1Label.Text = 's^1';
            app.s2Label = uilabel(app.OTFTab);
            app.s2Label.Text = 's^2';
            app.Lamp = uilamp(app.OTFTab);
            app.PZTab = uitab(app.TabGroup);
            app.PZTab.Title = 'P&Z';
            app.Lamp_2 = uilamp(app.PZTab);
            app.PolesLabel = uilabel(app.PZTab);
            app.PolesLabel.Text = 'Poles:';
            app.ZerosLabel = uilabel(app.PZTab);
            app.ZerosLabel.Text = 'Zeros:';
            app.EditField = uieditfield(app.PZTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = -1;
            app.EditField_2 = uieditfield(app.PZTab, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = -1;
            app.EditField_3 = uieditfield(app.PZTab, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = -1;
            app.EditField_4 = uieditfield(app.PZTab, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = -1;
            app.EditField_5 = uieditfield(app.PZTab, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = -1;
            app.EditField_6 = uieditfield(app.PZTab, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = -1;
            app.WorkspaceTab = uitab(app.TabGroup);
            app.WorkspaceTab.Title = 'Workspace';
            app.ListBoxLabel = uilabel(app.WorkspaceTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.WorkspaceTab);
            app.ListBox.Items = {'sys_tf', 'sys_ss2tf', 'sys_cartpend', 'sys_1'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'sys_tf';
            app.Lamp_3 = uilamp(app.WorkspaceTab);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ImpulseTab = uitab(app.TabGroup2);
            app.ImpulseTab.Title = 'Impulse';
            app.MagnitudeSpinnerLabel = uilabel(app.ImpulseTab);
            app.MagnitudeSpinnerLabel.Text = 'Magnitude';
            app.MagnitudeSpinner = uispinner(app.ImpulseTab);
            app.MagnitudeSpinner.ValueChangingFcn = createCallbackFcn(app, @MagnitudeSpinnerValueChanging, true);
            app.MagnitudeSpinner.Limits = [1 50];
            app.MagnitudeSpinner.Value = 10;
            app.StepTab = uitab(app.TabGroup2);
            app.StepTab.Title = 'Step';
            app.MagnitudeSpinner_2Label = uilabel(app.StepTab);
            app.MagnitudeSpinner_2Label.Text = 'Magnitude';
            app.MagnitudeSpinner_2 = uispinner(app.StepTab);
            app.MagnitudeSpinner_2.ValueChangingFcn = createCallbackFcn(app, @MagnitudeSpinner_2ValueChanging, true);
            app.MagnitudeSpinner_2.Limits = [1 50];
            app.MagnitudeSpinner_2.Value = 10;
            app.DelaySpinnerLabel = uilabel(app.StepTab);
            app.DelaySpinnerLabel.Text = 'Delay';
            app.DelaySpinner = uispinner(app.StepTab);
            app.DelaySpinner.ValueChangingFcn = createCallbackFcn(app, @DelaySpinnerValueChanging, true);
            app.SinTab = uitab(app.TabGroup2);
            app.SinTab.Title = 'Sin';
            app.MagnitudeSpinner_3Label = uilabel(app.SinTab);
            app.MagnitudeSpinner_3Label.Text = 'Magnitude';
            app.MagnitudeSpinner_3 = uispinner(app.SinTab);
            app.MagnitudeSpinner_3.ValueChangingFcn = createCallbackFcn(app, @MagnitudeSpinner_3ValueChanging, true);
            app.MagnitudeSpinner_3.Limits = [1 50];
            app.MagnitudeSpinner_3.Value = 10;
            app.Label = uilabel(app.SinTab);
            app.Label.Text = 'ω';
            app.Spinner_7 = uispinner(app.SinTab);
            app.Spinner_7.ValueChangingFcn = createCallbackFcn(app, @Spinner_7ValueChanging, true);
            app.Spinner_7.Value = 1;
            app.InputSignalButtonGroup = uibuttongroup(app.LeftPanel);
            app.InputSignalButtonGroup.Title = 'Input Signal';
            app.StepButton = uiradiobutton(app.InputSignalButtonGroup);
            app.StepButton.Text = 'Step';
            app.ImpulseButton = uiradiobutton(app.InputSignalButtonGroup);
            app.ImpulseButton.Text = 'Impulse';
            app.ImpulseButton.Value = true;
            app.SinButton = uiradiobutton(app.InputSignalButtonGroup);
            app.SinButton.Text = 'Sin';
            app.PIDPanel = uipanel(app.LeftPanel);
            app.PIDPanel.Title = 'PID';
            app.Spinner_4 = uispinner(app.PIDPanel);
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @Spinner_4ValueChanging, true);
            app.Spinner_4.Limits = [10 1000];
            app.Spinner_4.Value = 100;
            app.Spinner_5 = uispinner(app.PIDPanel);
            app.Spinner_5.Step = 0.5;
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5ValueChanging, true);
            app.Spinner_5.Limits = [10 1000];
            app.Spinner_5.Value = 30;
            app.Spinner_6 = uispinner(app.PIDPanel);
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6ValueChanging, true);
            app.Spinner_6.Limits = [10 1000];
            app.Spinner_6.Value = 25;
            app.KISliderLabel = uilabel(app.PIDPanel);
            app.KISliderLabel.Text = 'KI';
            app.KISlider = uislider(app.PIDPanel);
            app.KISlider.Limits = [0 30];
            app.KISlider.ValueChangedFcn = createCallbackFcn(app, @KISliderValueChangging, true);
            app.KDSliderLabel = uilabel(app.PIDPanel);
            app.KDSliderLabel.Text = 'KD';
            app.KDSlider = uislider(app.PIDPanel);
            app.KDSlider.Limits = [0 25];
            app.KDSlider.ValueChangingFcn = createCallbackFcn(app, @KDSliderValueChanging, true);
            app.KPSliderLabel = uilabel(app.PIDPanel);
            app.KPSliderLabel.Text = 'KP';
            app.KPSlider = uislider(app.PIDPanel);
            app.KPSlider.Limits = [1 100];
            app.KPSlider.ValueChangingFcn = createCallbackFcn(app, @KPSliderValueChanging, true);
            app.KPSlider.Value = 1;
            app.Spinner_8 = uispinner(app.PIDPanel);
            app.Spinner_8.Step = 0.1;
            app.Spinner_8.Limits = [1 Inf];
            app.Spinner_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_8ValueChanged, true);
            app.Spinner_8.Value = 1;
            app.Spinner_9 = uispinner(app.PIDPanel);
            app.Spinner_9.Step = 0.1;
            app.Spinner_9.Limits = [0 Inf];
            app.Spinner_9.ValueChangedFcn = createCallbackFcn(app, @Spinner_9ValueChanged, true);
            app.Spinner_10 = uispinner(app.PIDPanel);
            app.Spinner_10.Step = 0.1;
            app.Spinner_10.Limits = [0 Inf];
            app.Spinner_10.ValueChangedFcn = createCallbackFcn(app, @Spinner_10ValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.ResponseTab = uitab(app.TabGroup3);
            app.ResponseTab.Title = 'Response';
            app.UIAxes = uiaxes(app.ResponseTab);
            title(app.UIAxes, 'Response')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 20];
            app.UIAxes.YLim = [-10 20];
            app.UIAxes.ZLim = [0 1];
            app.GenerateButton = uibutton(app.ResponseTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ResetButton = uibutton(app.ResponseTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PerformanceIndexTextArea = uitextarea(app.ResponseTab);
            app.PerformanceIndexTextArea.Editable = 'off';
            app.PerformanceIndexTextArea.Visible = 'off';
            app.SimulationTab = uitab(app.TabGroup3);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes2 = uiaxes(app.SimulationTab);
            title(app.UIAxes2, 'Simulation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SimulateButton = uibutton(app.SimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.TimeEditFieldLabel = uilabel(app.SimulationTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TimeEditField.Value = 10;
            app.PositionEditFieldLabel = uilabel(app.SimulationTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.SimulationTab, 'numeric');
            app.PositionEditField.Value = 2;
            app.AngleEditFieldLabel = uilabel(app.SimulationTab);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.SimulationTab, 'numeric');
            app.AngleEditField.Value = 0.1;
            app.Lamp_4 = uilamp(app.SimulationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end